classdef MD < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        FormDefinitionMenu             matlab.ui.container.Menu
        AddQuestionandOptionMenu       matlab.ui.container.Menu
        UpdateQuestionMenu             matlab.ui.container.Menu
        ImageProcessingDefinitionMenu  matlab.ui.container.Menu
        AddImageProcessingMenu         matlab.ui.container.Menu
        UpdateImageProcessingMenu      matlab.ui.container.Menu
        RunImageProcessingMenu         matlab.ui.container.Menu
        GetDatasMenu                   matlab.ui.container.Menu
        FromImageToExcelMenu           matlab.ui.container.Menu
        StepByStepReadImageMenu        matlab.ui.container.Menu
        Hyperlink                      matlab.ui.control.Hyperlink
        UpdateImageProcessPanel        matlab.ui.container.Panel
        ProcessNameEditField           matlab.ui.control.EditField
        ProcessNameEditFieldLabel      matlab.ui.control.Label
        ImageProcessTextArea           matlab.ui.control.TextArea
        ImageProcessTextAreaLabel      matlab.ui.control.Label
        ProcessOrderDropDown           matlab.ui.control.DropDown
        ProcessOrderDropDownLabel      matlab.ui.control.Label
        AddImageProcessPanel           matlab.ui.container.Panel
        ProcessNameEditField_2         matlab.ui.control.EditField
        ProcessNameEditField_2Label    matlab.ui.control.Label
        AddImageProcessButton          matlab.ui.control.Button
        ImageProcessTextArea_2         matlab.ui.control.TextArea
        ImageProcessTextArea_2Label    matlab.ui.control.Label
        EditQuestionPanel              matlab.ui.container.Panel
        ProcessOrderDropDown_2         matlab.ui.control.DropDown
        ProcessOrderDropDown_2Label    matlab.ui.control.Label
        CanYouEditTheQuestionButtonGroup  matlab.ui.container.ButtonGroup
        NoYouCantEditItButton          matlab.ui.control.RadioButton
        YesYouCanEditItButton          matlab.ui.control.RadioButton
        QuestionOrderDropDown          matlab.ui.control.DropDown
        QuestionOrderDropDownLabel     matlab.ui.control.Label
        QuestionEditField              matlab.ui.control.EditField
        QuestionEditFieldLabel         matlab.ui.control.Label
        UpdateQuestionButton           matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function loadVeriable(app)
            global filenameExcel;
            global filenameSaveExcel;
            global excelQuestions;
            global excelImageProcessing;
            global excelProcessing;
            global ImageProcessedFiles;
            global EditedFiles;
            global ImageFiles;
            global ReferanceImageFiles;
            global ImageProcessOrder;
            ImageProcessOrder = 0;

            % Get program information from ApplicationInformations excel
            % file in MD folder
            filenameExcel = string(getenv('USERPROFILE')) + '\Desktop\MD\ApplicationInformations.xlsx';
            filenameSaveExcel = string(getenv('USERPROFILE')) + '\Desktop\MD\ImageProcessedResult.xlsx';
            ImageProcessedFiles = string(getenv('USERPROFILE')) +'\Desktop\MD\ImageProcessedFolder';

            % checks files if the files do not exist, create them.
             if ~exist(ImageProcessedFiles, 'dir')
                mkdir(ImageProcessedFiles);
             end
             EditedFiles = string(getenv('USERPROFILE')) +'\Desktop\MD\ImageEditedFolder';
             if ~exist(EditedFiles, 'dir')
                mkdir(EditedFiles);
             end
             ImageFiles = string(getenv('USERPROFILE')) +'\Desktop\MD\ImageFolder';
             if ~exist(ImageFiles, 'dir')
                mkdir(ImageFiles);
                winopen(string(getenv('USERPROFILE')) +'\Desktop\MD\ImageFolder');
                uiwait(msgbox("Paste Scan Images in ImageFolder Folder For Image Processing","Success","modal"));
             end
             ReferanceImageFiles = string(getenv('USERPROFILE')) +'\Desktop\MD\ReferanceImageFolder';
             if ~exist(ReferanceImageFiles, 'dir')
                mkdir(ReferanceImageFiles);
                winopen(string(getenv('USERPROFILE')) +'\Desktop\MD\ReferanceImageFolder');
                uiwait(msgbox("Paste Referance Images in ReferanceImageFolder Folder","Success","modal"));
             end
            % if excel file does not found, create excel file and Sheet
            if ~isfile(filenameExcel)
                writematrix("", filenameExcel,'Sheet','QUESTIONS','Range','A1' );
                writematrix("", filenameExcel,'Sheet','OPTIONS','Range','A1' );
                writematrix("1", filenameExcel,'Sheet','IMAGEPROCESSNAME','Range','A1' );
                writematrix("Clear Image Coordinat-1", filenameExcel,'Sheet','IMAGEPROCESSNAME','Range','B1' );
                writematrix("noisyimg = imnoise(imageProcessed,'Salt & Pepper', 0.1);imageProcessed= medfilt2(noisyimg,[7 7]);imageProcessed = imageProcessed < 240;conn = conndef(2,'maximal');imageProcessed = bwareaopen(imageProcessed, 64,conn);rp = regionprops(imageProcessed, 'PixelIdxList', 'Eccentricity', 'Orientation');% Get high eccentricity and orientations at 90 and 0 degrees;rp = rp([rp.Eccentricity] > 0.95 & (abs([rp.Orientation]) < 2 | abs([rp.Orientation]) > 88));imageProcessed(vertcat(rp.PixelIdxList)) = false;%dikey çizgileri sil;imageProcessed= im2double(imageProcessed);imageProcessed= medfilt2(imageProcessed,[1 5]);imageProcessed= imadjust(imageProcessed,[0.15 0.85]);", filenameExcel,'Sheet','IMAGEPROCESSNAME','Range','C1' );
                writematrix("2", filenameExcel,'Sheet','IMAGEPROCESSNAME','Range','A2' );
                writematrix("Clear Image Coordinat-2", filenameExcel,'Sheet','IMAGEPROCESSNAME','Range','B2' );
                writematrix("noisyimg = imnoise(imageProcessed,'Salt & Pepper', 0.1);imageProcessed= medfilt2(noisyimg,[9 9]);imageProcessed = imageProcessed < 150;conn = conndef(2,'maximal');imageProcessed = bwareaopen(imageProcessed, 64,conn);rp = regionprops(imageProcessed, 'PixelIdxList', 'Eccentricity', 'Orientation');% Get high eccentricity and orientations at 90 and 0 degrees;rp = rp([rp.Eccentricity] > 0.95 & (abs([rp.Orientation]) < 2 | abs([rp.Orientation]) > 88));imageProcessed(vertcat(rp.PixelIdxList)) = false;%dikey çizgileri sil;imageProcessed= im2double(imageProcessed);imageProcessed= medfilt2(imageProcessed,[1 5]);imageProcessed= imadjust(imageProcessed,[0.15 0.85]);", filenameExcel,'Sheet','IMAGEPROCESSNAME','Range','C2' );
                writematrix("", filenameExcel,'Sheet','IMAGEPROCESSES','Range','A1' );
            else
                %read excel file for question informations
                excelQuestions = readcell(filenameExcel,"Sheet",'QUESTIONS','Range','A1');
                excelQuestions(cellfun(@(x) any(ismissing(x)), excelQuestions)) = {''};
                try
                    % if question informations exist,
                    % read question informatins
                    canChange = excelQuestions{1,7};    
                    for i=1:height(excelQuestions)
                        app.QuestionOrderDropDown.Items(i) =  cellstr(string(excelQuestions(i,1)));
                    end                       
                    app.QuestionEditField.Value = string(excelQuestions(1,4)); 
                catch ME
                   if (strcmp(ME.identifier,'MATLAB:badsubscript'))
                      canChange = "";
                   end
                   % rethrow(ME)
                end     
                if canChange == "Question InformationCompleted"
                   app.NoYouCantEditItButton.Value = 1;
                else
                   app.YesYouCanEditItButton.Value = 1;
                end 
                
            end

            excelImageProcessing = readcell(filenameExcel,"Sheet",'IMAGEPROCESSNAME','Range','A1');
            excelImageProcessing(cellfun(@(x) any(ismissing(x)), excelImageProcessing)) = {''};
            if excelImageProcessing(1,1) ~= ""
               for i=1:height(excelImageProcessing)
                  ImageProcessOrder = ImageProcessOrder + 1;
                  app.ProcessOrderDropDown.Items(i) = cellstr(string(excelImageProcessing(i,1)));
                  app.ProcessOrderDropDown_2.Items(i) = cellstr(string(excelImageProcessing(i,1)));
               end
               app.ProcessNameEditField.Value = string(excelImageProcessing(1,2));
               app.ImageProcessTextArea.Value = split(excelImageProcessing(1,3),";");    
            end
            % excelProcessing = readcell(filenameExcel,"Sheet",'IMAGEPROCESSES','Range','A1');
            % excelProcessing(cellfun(@(x) any(ismissing(x)), excelProcessing)) = {''};
           
        end

        % Menu selected function: AddQuestionandOptionMenu
        function addQuestion(app, event)
  
            global filenameExcel;
            global excelQuestions;
            global excelImageProcessing;
            global excelOptions;
            global ImageProcessedFiles;
            global EditedFiles;
            global ImageFiles;
            global ReferanceImageFiles;
            is_contiue = 1;


            app.FormDefinitionMenu.ForegroundColor = [0,1,0];
            app.FormDefinitionMenu.Enable = "off";
            app.ImageProcessingDefinitionMenu.Enable = "off";
            app.RunImageProcessingMenu.Enable = "off";
            app.GetDatasMenu.Enable = "off";

            app.AddImageProcessPanel.Visible = "off";
            app.UpdateImageProcessPanel.Visible = "off";
            app.EditQuestionPanel.Visible = "off";

            SelectedQuestionImageUrl = ReferanceImageFiles;
            SelectedQuestion = 1;   
            SelectedQuestionRow = 1;
            SelectedQuestionOptionCountOrder = 0;
            %Read excell
            excelQuestions = readcell(filenameExcel,"Sheet",'QUESTIONS','Range','A1');
            excelQuestions(cellfun(@(x) any(ismissing(x)), excelQuestions)) = {''};
            excelOptions = readcell(filenameExcel,"Sheet",'OPTIONS','Range','A1');
            excelOptions(cellfun(@(x) any(ismissing(x)), excelOptions)) = {''};
            if height(app.QuestionOrderDropDown.Items) > 0
                questionRowCount = height(excelQuestions);
                excelOptionsRowCount = height(excelOptions);
            else
                questionRowCount = 0;
                excelOptionsRowCount = 0;
            end
            
            uiwait(msgbox("Select image to add options coordinat. When image is opened, you can select any keyword to start it. After click keyword you can mark options of quesiton","Success","modal"));
           
            while is_contiue
                if SelectedQuestionRow > questionRowCount
                    % SelectedQuestion = "";
                    SelectedQuestionTitle = "";
                    SelectedQuestionOptionCount = 0;
                    SelectedQuestionCompletedInfo = "";
                else
                    SelectedQuestion = string(excelQuestions(SelectedQuestionRow,2));
                    SelectedQuestionTitle = string(excelQuestions(SelectedQuestionRow,4));
                    SelectedQuestionImageUrl = string(excelQuestions(SelectedQuestionRow,5));
                    SelectedQuestionOptionCount =  str2double(excelQuestions(SelectedQuestionRow,6));
                    SelectedQuestionCompletedInfo = string(excelQuestions(SelectedQuestionRow,7));
                end
                if SelectedQuestionCompletedInfo ~= "Question InformationCompleted"
                %select image
                    [file,path] = uigetfile({'*.jpeg;*.JPEG;*.png;*.PNG;*.jpg;*.JPG';'*.*'}, 'Select a file', SelectedQuestionImageUrl);
                    if isequal(file,0)
                       disp('User selected Cancel');
                       is_contiue = 0;
                    else
                       SelectedQuestionImageUrl = fullfile(path,file);
                       selectedImage = imread(SelectedQuestionImageUrl);   
                       imshow(selectedImage);  
                       % hold on;  
                       while 1
                          w = waitforbuttonpress;
                          %After click keyword you can contiue
                          if (w == 1)
                              break;
                          end
                       end
                    %input is controled by this code
                    while is_contiue
                        Response = inputdlg({'Question Order:','Question:','Option of Question'},'Select Cancel to Quit',[1 50], [SelectedQuestion SelectedQuestionTitle SelectedQuestionOptionCount]);
                         if isempty(Response)
                            break;
                         end
                        SelectedQuestion = str2double(Response(1));
                        SelectedQuestionTitle = string(Response(2));
                        SelectedQuestionOptionCount =  str2double(Response(3));
                    
                        if  isnan(SelectedQuestion) && isnan(SelectedQuestionOptionCount)
                            message = sprintf('Question required number');
                            uiwait(warndlg(message));
                        elseif  isnan(SelectedQuestion)
                            message = sprintf(' Quesiton required number.');
                            uiwait(warndlg(message));
                        elseif  isnan(SelectedQuestionOptionCount)
                            message = sprintf('Option required number');
                            uiwait(warndlg(message));
                        elseif SelectedQuestionTitle ==""
                            message = sprintf('required question title');
                            uiwait(warndlg(message));
                        else
                        break;
                        end
                    end
                    if isempty(Response)
                        break;
                    end
            %input save as excell
                    [questionOptionCoordinats, questionOptionTitle] = readPoints(excelOptions, excelOptionsRowCount, SelectedQuestionOptionCountOrder, SelectedQuestionOptionCount);
                    % save question information
                        selectedRow = string(SelectedQuestionRow);
                        writematrix(string(SelectedQuestionRow), filenameExcel,'Sheet','QUESTIONS','Range',('A' + selectedRow));
                        writematrix(string(SelectedQuestion), filenameExcel,'Sheet','QUESTIONS','Range',('B' + selectedRow));
                        writematrix("1", filenameExcel,'Sheet','QUESTIONS','Range',('C' + selectedRow));
                        writematrix(string(SelectedQuestionTitle), filenameExcel,'Sheet','QUESTIONS','Range',('D' + selectedRow));
                        writematrix(string(SelectedQuestionImageUrl), filenameExcel,'Sheet','QUESTIONS','Range',('E' + selectedRow));
                        writematrix(string(SelectedQuestionOptionCount), filenameExcel,'Sheet','QUESTIONS','Range',('F' + selectedRow));
                        writematrix("Question InformationCompleted", filenameExcel,'Sheet','QUESTIONS', 'Range',('G' + selectedRow));
                
                        for i=1:SelectedQuestionOptionCount
                            SelectedQuestionOptionCountOrder = SelectedQuestionOptionCountOrder + 1;
                    
                            writematrix(string(SelectedQuestionRow), filenameExcel,'Sheet','OPTIONS','Range',('A' + string(SelectedQuestionOptionCountOrder)));
                            writematrix(string(questionOptionTitle(i)), filenameExcel,'Sheet','OPTIONS','Range',('C' + string(SelectedQuestionOptionCountOrder)));
                            writematrix(string(questionOptionCoordinats(i,1)), filenameExcel,'Sheet','OPTIONS','Range',('D' + string(SelectedQuestionOptionCountOrder)));
                            writematrix(string(questionOptionCoordinats(i,2)), filenameExcel,'Sheet','OPTIONS','Range',('E' + string(SelectedQuestionOptionCountOrder)));
                            writematrix(string(questionOptionCoordinats(i,3)), filenameExcel,'Sheet','OPTIONS','Range',('F' + string(SelectedQuestionOptionCountOrder)));
                            writematrix(string(questionOptionCoordinats(i,4)), filenameExcel,'Sheet','OPTIONS','Range',('G' + string(SelectedQuestionOptionCountOrder)));
                        end
                    end
                else
                     SelectedQuestionOptionCountOrder = SelectedQuestionOptionCountOrder + SelectedQuestionOptionCount;
                end
                SelectedQuestionRow = SelectedQuestionRow + 1;
            end     
            app.FormDefinitionMenu.Enable = "on";
            app.ImageProcessingDefinitionMenu.Enable = "on";
            app.RunImageProcessingMenu.Enable = "on";
            app.GetDatasMenu.Enable = "on";
            app.FormDefinitionMenu.ForegroundColor = [0,0,0];

        end

        % Callback function
        function secme(app, event)
           
        end

        % Value changed function: QuestionOrderDropDown
        function changeQuestion(app, event)
            global excelQuestions;
            value = str2double(app.QuestionOrderDropDown.Value);
            canChange = excelQuestions{value,7};
            selected_process = excelQuestions{value,3};
            if ~isempty(selected_process)
                app.ProcessOrderDropDown_2.Value = selected_process;
            end
            app.QuestionEditField.Value = excelQuestions{value,4};
            if canChange == "Question InformationCompleted"
                app.NoYouCantEditItButton.Value = 1;
            else
                app.YesYouCanEditItButton.Value = 1;
            end
            
        end

        % Button pushed function: UpdateQuestionButton
        function updateQuestionInformations(app, event)
            global filenameExcel;
            global excelQuestions;
            canChange = "You Can Change Question Information";
            if app.NoYouCantEditItButton.Value == 1 
                canChange = "Question Information Completed";
            end
            SelectedQuestionRow_string = string(app.QuestionOrderDropDown.Value);
            SelectedQuestionRow = app.QuestionOrderDropDown.Value;
            SelectedQuestionTitle = app.QuestionEditField.Value; 
            writematrix(string(app.ProcessOrderDropDown_2.Value), filenameExcel,'Sheet','QUESTIONS','Range',('C' + SelectedQuestionRow_string));
            writematrix(string(SelectedQuestionTitle), filenameExcel,'Sheet','QUESTIONS','Range',('D' + SelectedQuestionRow_string));
            writematrix(canChange, filenameExcel,'Sheet','QUESTIONS','Range',('G' + SelectedQuestionRow_string));
            excelQuestions(SelectedQuestionRow,4) = {SelectedQuestionTitle};
            excelQuestions(SelectedQuestionRow,7) = {canChange};
            excelQuestions(SelectedQuestionRow,3) = {app.ProcessOrderDropDown_2.Value};
            uiwait(msgbox("Updated Question","Success","modal"));

        end

        % Menu selected function: UpdateQuestionMenu
        function SoruGuncelleBtn(app, event)
            global filenameExcel;
            %read excel file for question informations
                excelQuestions = readcell(filenameExcel,"Sheet",'QUESTIONS','Range','A1');
                excelQuestions(cellfun(@(x) any(ismissing(x)), excelQuestions)) = {''};
              try
                    % if question informations exist,
                    % read question informatins
                    canChange = excelQuestions{1,7};    
                    for i=1:height(excelQuestions)
                        app.QuestionOrderDropDown.Items(i) =  cellstr(string(excelQuestions(i,1)));
                    end                       
                    app.QuestionEditField.Value = string(excelQuestions(1,4)); 
                catch ME
                   if (strcmp(ME.identifier,'MATLAB:badsubscript'))
                      
                   end
                end  
            app.AddImageProcessPanel.Visible = "off";
            app.UpdateImageProcessPanel.Visible = "off";
            app.EditQuestionPanel.Visible = "on";
        end

        % Menu selected function: AddImageProcessingMenu
        function GoruntuIslemEkleBtn(app, event)
            app.AddImageProcessPanel.Visible = "on";
            app.UpdateImageProcessPanel.Visible = "off";
            app.EditQuestionPanel.Visible = "off";
        end

        % Value changed function: ImageProcessTextArea, 
        % ...and 1 other component
        function functionProcessChange(app, event)
            global filenameExcel;
            global ReferanceImageFiles;
            goruntu_islem = app.ProcessOrderDropDown.Value; 
            value = app.ImageProcessTextArea.Value;
            secilen_metin ="";
             for i=1:height(value)
                 selected_item_string = strtrim(value(i));
                 if selected_item_string ~= ""
                    secilen_metin = secilen_metin + selected_item_string + ";";
                 end
             end
             writematrix(app.ProcessNameEditField.Value, filenameExcel,'Sheet','IMAGEPROCESSNAME','Range',('B' + string(goruntu_islem)));
             writematrix(secilen_metin, filenameExcel,'Sheet','IMAGEPROCESSNAME','Range',('C' + string(goruntu_islem)));
             uiwait(msgbox("Updated Image Processing. Select Image For Image Processing.","Success","modal"));
            % for i=1:height(value)
            %       writematrix(string(SelectedQuestionRow), filenameExcel,'Sheet','OPTIONS','Range',('A' + string(SelectedQuestionOptionCountOrder)));
            %     writematrix(string(SelectedQuestionRow), filenameExcel,'Sheet','OPTIONS','Range',('A' + string(SelectedQuestionOptionCountOrder)));
            %      % itemIndex = str2double(excelQuestions{i,1});
            %     app.ProcessOrderDropDown.Items(i) =  value(i);
            %  end

            [file,path] = uigetfile({'*.jpeg;*.JPEG;*.png;*.PNG;*.jpg;*.JPG';'*.*'}, 'Select a file', ReferanceImageFiles);
            if isequal(file,0)
               disp('User selected Cancel');
            else
               SelectedQuestionImageUrl = fullfile(path,file);
                    imageProcessed_rgb = imread(SelectedQuestionImageUrl);
                    imageProcessed = rgb2gray(imageProcessed_rgb);

            tmp = tempname;
            mkdir(tmp);
            newFile = fullfile(tmp,'ANewFile.m');
            fid = fopen(newFile,'w');
            fprintf(fid,secilen_metin);
            fclose(fid);
            run(newFile);  

               imshow(imageProcessed); 
            end
            
        end

        % Value changed function: ProcessOrderDropDown
        function islemChange(app, event)
            global excelImageProcessing;
            value = str2double(app.ProcessOrderDropDown.Value);
            app.ProcessNameEditField.Value = string(excelImageProcessing(value,2));
             app.ImageProcessTextArea.Value = split(excelImageProcessing(value,3),";"); 
                   
        end

        % Button pushed function: AddImageProcessButton
        function InsertProcess(app, event)
            global ImageProcessOrder; 
            global excelImageProcessing;
            global filenameExcel;
            ImageProcessOrder = ImageProcessOrder + 1;
            value = app.ImageProcessTextArea_2.Value;
            secilen_metin ="";
             for i=1:height(value)
               secilen_metin = secilen_metin + value(i) + ";";
             end
             writematrix(string(ImageProcessOrder), filenameExcel,'Sheet','IMAGEPROCESSNAME','Range',('A' + string(ImageProcessOrder)));
             writematrix(secilen_metin, filenameExcel,'Sheet','IMAGEPROCESSNAME','Range',('C' + string(ImageProcessOrder)));
             writematrix(app.ProcessNameEditField_2.Value, filenameExcel,'Sheet','IMAGEPROCESSNAME','Range',('B' + string(ImageProcessOrder)));   
             % % % app.ImageProcessTextArea_2 = {""};
             excelImageProcessing = readcell(filenameExcel,"Sheet",'IMAGEPROCESSNAME','Range','A1');
             excelImageProcessing(cellfun(@(x) any(ismissing(x)), excelImageProcessing)) = {''};
                if excelImageProcessing(1,1) ~= ""
                   for i=1:height(excelImageProcessing)
                      ImageProcessOrder = ImageProcessOrder + 1;
                      app.ProcessOrderDropDown.Items(i) = cellstr(string(excelImageProcessing(i,1)));
                      app.ProcessOrderDropDown_2.Items(i) = cellstr(string(excelImageProcessing(i,1)));
                   end
                   app.ProcessOrderDropDown.Value = string(ImageProcessOrder);
                   app.ImageProcessTextArea.Value = split(excelImageProcessing(ImageProcessOrder,3),";"); 
                end
                UpdateProcess(app, event);
                uiwait(msgbox("Added Image Process","Success","modal"));
        end

        % Menu selected function: UpdateImageProcessingMenu
        function UpdateProcess(app, event)
            app.AddImageProcessPanel.Visible = "off";
            app.UpdateImageProcessPanel.Visible = "on";
            app.EditQuestionPanel.Visible = "off";
        end

        % Menu selected function: RunImageProcessingMenu
        function ImageProcessing(app, event)
            global filenameExcel;
            global excelQuestions;
            global excelImageProcessing;
            global excelOptions;
            global ImageProcessedFiles;
            global EditedFiles;
            global ImageFiles;
            global ReferanceImageFiles;
            global ImageProcessOrder;           

            app.RunImageProcessingMenu.ForegroundColor = [0,1,0];
            app.FormDefinitionMenu.Enable = "off";
            app.ImageProcessingDefinitionMenu.Enable = "off";
            app.RunImageProcessingMenu.Enable = "off";
            app.GetDatasMenu.Enable = "off";

            app.AddImageProcessPanel.Visible = "off";
            app.UpdateImageProcessPanel.Visible = "off";
            app.EditQuestionPanel.Visible = "off"; 
            % uiwait(msgbox("Start to Process.","Success","modal"));

            excelQuestions = readcell(filenameExcel,"Sheet",'QUESTIONS','Range','A1');
            excelQuestions(cellfun(@(x) any(ismissing(x)), excelQuestions)) = {''};
            excelOptions = readcell(filenameExcel,"Sheet",'OPTIONS','Range','A1');
            excelOptions(cellfun(@(x) any(ismissing(x)), excelOptions)) = {''};
 
            excelImageProcessing = readcell(filenameExcel,"Sheet",'IMAGEPROCESSNAME','Range','A1');
            excelImageProcessing(cellfun(@(x) any(ismissing(x)), excelImageProcessing)) = {''};
 
             files = dir(ImageFiles);
            % Get a logical vector that tells which is a directory.
            dirFlags = [files.isdir];
            % Extract only those that are directories.
            subDirs = files(dirFlags); % A structure with extra info.
            % Get only the folder names into a cell array.
            subDirsNames = {subDirs(3:end).name};            
            total_scan_folder = length(subDirsNames);

            for selected_folder_id=1:total_scan_folder
                for selected_question_id=1:height(excelQuestions)
                    try
                    selectedQuestion =  str2double(excelQuestions(selected_question_id,2));
                    selectedImageProcessingId = str2double(excelQuestions(selected_question_id,3));
                    selectedImageProcessing = string(excelImageProcessing(selectedImageProcessingId,3));
                    selectedStandartImageFolder = string(excelQuestions(selected_question_id,5));
                    [filepath,imageName,imageExt] = fileparts(selectedStandartImageFolder);
                    selectedImageFolder = ImageFiles + '\' + subDirsNames(selected_folder_id) + '\' + imageName + imageExt;
                    catch ME
                        break;
                    end
 
                    standart_resim_rgb = imread(selectedStandartImageFolder);
                    standart_resim = rgb2gray(standart_resim_rgb);
                    imageProcessed_rgb = imread(selectedImageFolder);
                    imageProcessed = rgb2gray(imageProcessed_rgb);
                    
                    ptsOriginal = detectSURFFeatures(standart_resim);
                    ptsDistorted = detectSURFFeatures(imageProcessed);
                    [featuresOriginal,validPtsOriginal] = extractFeatures(standart_resim, ptsOriginal);
                    [featuresDistorted,validPtsDistorted] = extractFeatures(imageProcessed, ptsDistorted);
                    indexPairs = matchFeatures(featuresOriginal,featuresDistorted);
                    matchedOriginal = validPtsOriginal(indexPairs(:,1));
                    matchedDistorted = validPtsDistorted(indexPairs(:,2));
                
                    
                    [tform, inlierIdx] = estgeotform2d(matchedDistorted,matchedOriginal,'similarity');
                    inlierDistorted = matchedDistorted(inlierIdx,:);
                    inlierOriginal = matchedOriginal(inlierIdx,:);                   
                    
                    invTform = invert(tform);
                    Ainv = invTform.A;
                    
                    ss = Ainv(1,2);
                    sc = Ainv(1,1);
                    scaleRecovered = hypot(ss,sc);
                    thetaRecovered = atan2d(-ss,sc);

                    tmp = tempname;
                    mkdir(tmp);
                    newFile = fullfile(tmp,'ANewFile.m');
                    fid = fopen(newFile,'w');
                    fprintf(fid,selectedImageProcessing);
                    fclose(fid);
                    run(newFile);

                    outputView = imref2d(size(standart_resim));   
                    rotated_image_rgb = imwarp(imageProcessed_rgb, tform,OutputView=outputView);
                    rotated_image = imwarp(imageProcessed, tform,OutputView=outputView);
                   
                     islemImageProcessedFiles = string(getenv('USERPROFILE')) +'\Desktop\MD\ImageProcessedFolder\' + string(selected_folder_id);
                     if ~exist(islemImageProcessedFiles, 'dir')
                        mkdir(islemImageProcessedFiles);
                     end
                     islemEditedFiles = string(getenv('USERPROFILE')) +'\Desktop\MD\ImageEditedFolder\' + string(selected_folder_id);
                     if ~exist(islemEditedFiles, 'dir')
                        mkdir(islemEditedFiles);
                     end
                    image_rgb_save_url = string(getenv('USERPROFILE')) +'\Desktop\MD\ImageEditedFolder\' + string(selected_folder_id) + '\' + string(selectedQuestion) + imageExt;
                    image_save_url = string(getenv('USERPROFILE')) +'\Desktop\MD\ImageProcessedFolder\' + string(selected_folder_id) + '\' + string(selectedQuestion) + imageExt;
                    imwrite(rotated_image_rgb, image_rgb_save_url, "Comment","My JPEG file");
                    imwrite(rotated_image, image_save_url, "Comment","My JPEG file");


                end
            end  
            app.FormDefinitionMenu.Enable = "on";
            app.ImageProcessingDefinitionMenu.Enable = "on";
            app.RunImageProcessingMenu.Enable = "on";
            app.GetDatasMenu.Enable = "on";
            uiwait(msgbox("ImageFolder Processed","Success","modal"));
            winopen(string(getenv('USERPROFILE')) +'\Desktop\MD\ImageProcessedFolder');
            app.RunImageProcessingMenu.ForegroundColor = [0,0,0];
        end

        % Menu selected function: StepByStepReadImageMenu
        function showImageProcessing(app, event)
            global filenameSaveExcel;
            global filenameExcel;
            global excelQuestions;
            global excelImageProcessing;
            global excelOptions;
            global ImageProcessedFiles;
            global EditedFiles;
            global ImageFiles;
            global ReferanceImageFiles;
            global ImageProcessOrder;           

            app.GetDatasMenu.ForegroundColor = [0,1,0];
            app.FormDefinitionMenu.Enable = "off";
            app.ImageProcessingDefinitionMenu.Enable = "off";
            app.RunImageProcessingMenu.Enable = "off";
            app.GetDatasMenu.Enable = "off";

            app.AddImageProcessPanel.Visible = "off";
            app.UpdateImageProcessPanel.Visible = "off";
            app.EditQuestionPanel.Visible = "off"; 

            excelQuestions = readcell(filenameExcel,"Sheet",'QUESTIONS','Range','A1');
            excelQuestions(cellfun(@(x) any(ismissing(x)), excelQuestions)) = {''};

            excelOptions = readcell(filenameExcel,"Sheet",'OPTIONS','Range','A1');
            excelOptions(cellfun(@(x) any(ismissing(x)), excelOptions)) = {''};
 
            excelImageProcessing = readcell(filenameExcel,"Sheet",'IMAGEPROCESSNAME','Range','A1');
            excelImageProcessing(cellfun(@(x) any(ismissing(x)), excelImageProcessing)) = {''};
 
             files = dir(ImageFiles);
            % Get a logical vector that tells which is a directory.
            dirFlags = [files.isdir];
            % Extract only those that are directories.
            subDirs = files(dirFlags); % A structure with extra info.
            % Get only the folder names into a cell array.
            subDirsNames = {subDirs(3:end).name};            
            total_scan_folder = length(subDirsNames);
            
            oldselectedQuestionIndex = "-1";
            oldselectedAnswer = "";
            selectedAnswer = "";
            afterOptionQuestionIndex = "";
            

            uiwait(msgbox("Click Keyboard For Next Itiration.","Success","modal"));
            
            total_selected_pixel = 0;
            selected_x_coordinat = 0;
            selected_y_coordinat = 0;
            for selected_folder_id=1:total_scan_folder
                % Write folder id in excel
                writematrix(string(selected_folder_id), filenameSaveExcel,'Sheet',1,'Range', ('A' + string(selected_folder_id + 1)));
                for selected_option_id=1:height(excelOptions)
                   
                    selectedQuestionIndex =  str2double(string(excelOptions(selected_option_id,1)));
                    selectedQuestionTitle =  string(excelQuestions(selectedQuestionIndex,4));
                    selectedQuestion =  str2double(string(excelQuestions(selectedQuestionIndex,2)));
                    selectedOptionTitle =  string(excelOptions(selected_option_id,3));
                    % selectedImageProcessingId = str2double(excelQuestions(selectedQuestion,3));
                    % selectedImageProcessing = string(excelImageProcessing(selectedImageProcessingId,3));
                    selectedStandartImageFolder = string(excelQuestions(selectedQuestionIndex,5));
                    [filepath,imageName,imageExt] = fileparts(selectedStandartImageFolder);
                    % selectedImageFolder = ImageProcessedFiles + '\' + subDirsNames(selected_folder_id) + '\' + imageName + imageExt;
                    selectedProcessedImageFolder = string(getenv('USERPROFILE')) +'\Desktop\MD\ImageProcessedFolder\' + string(selected_folder_id) + '\' + string(selectedQuestion) + imageExt;
                    selectImageFolder = string(getenv('USERPROFILE')) +'\Desktop\MD\ImageEditedFolder\' + string(selected_folder_id) + '\' + string(selectedQuestion) + imageExt;
                     if ~isfile(selectedProcessedImageFolder)
                         % File does not exist.
                         ImageProcessing(app, event);
                    end
                    selectedProcessedImage = imread(selectedProcessedImageFolder);
                    selectImage = imread(selectImageFolder);
                    
                    part_of_image_x_coordinat = str2double(excelOptions(selected_option_id,4));
                    part_of_image_y_coordinat = str2double(excelOptions(selected_option_id,5));
                    part_of_image_width = str2double(excelOptions(selected_option_id,6));
                    part_of_image_heigth = str2double(excelOptions(selected_option_id,7));
                    %get part of image to selected for answer
                    part_of_image = imcrop(selectedProcessedImage,[part_of_image_x_coordinat, part_of_image_y_coordinat, part_of_image_width, part_of_image_heigth]);

                    %soru değişince yeni resim göster
                    if oldselectedQuestionIndex ~= string(excelOptions(selected_option_id,1))    
                        waitforbuttonpress;
                        % % while 1         
                        % %     % 0 if it detects a click
                        % %     % 1 if it detects a key press
                        % %     w = waitforbuttonpress;
                        % %     if (w == 1 || w == 0 )
                        % %       break;
                        % %     end
                        % % end
                        subplot(1,2,1);
                        imshow(selectedProcessedImage);
                        title(string(selected_folder_id) + ".Dosya: " + selectedStandartImageFolder);
                        hold on; 
                        subplot(1,2,2);
                        imshow(selectImage);
                        title(" Soru: " + selectedQuestionTitle);
                        hold on;
                        oldselectedQuestionIndex = string(excelOptions(selected_option_id,1)); 
                    end
                    subplot(1,2,1);
                    rectangle('Position',[part_of_image_x_coordinat , part_of_image_y_coordinat, part_of_image_width , part_of_image_heigth], 'EdgeColor','red');
                    subplot(1,2,2);
                    rectangle('Position',[part_of_image_x_coordinat , part_of_image_y_coordinat, part_of_image_width , part_of_image_heigth], 'EdgeColor','red');    
                    
                    %find selected option
                    total_dedect_pixel_from_part_of_image = sum(part_of_image,"all");
                    if  total_dedect_pixel_from_part_of_image > 0 
                        if  total_dedect_pixel_from_part_of_image > total_selected_pixel
                            total_selected_pixel = total_dedect_pixel_from_part_of_image;
                            selectedAnswer = string(excelOptions(selected_option_id,3)); 
                            selected_x_coordinat = part_of_image_x_coordinat;
                            selected_y_coordinat = part_of_image_y_coordinat;
                        end
                    end
                    if selected_option_id == height(excelOptions)
                         writematrix(selectedQuestionTitle, filenameSaveExcel,'Sheet',1,'Range',createExcelColumnName(1,(selectedQuestionIndex + 1)));
                         if selectedAnswer ~= ""
                            writematrix(selectedAnswer, filenameSaveExcel,'Sheet',1,'Range',createExcelColumnName((selected_folder_id + 1),(selectedQuestionIndex+1)));
                             
                            subplot(1,2,1);
                            rectangle('Position',[selected_x_coordinat , selected_y_coordinat, part_of_image_width , part_of_image_heigth], 'EdgeColor','green');
                            subplot(1,2,2);
                            rectangle('Position',[selected_x_coordinat , selected_y_coordinat, part_of_image_width , part_of_image_heigth], 'EdgeColor','green');
                        end   
                        total_selected_pixel = 0;
                        selectedAnswer = "";
                    else 
                        afterOptionQuestionIndex =  str2double(string(excelOptions((selected_option_id+1),1)));
                        if selectedQuestionIndex == afterOptionQuestionIndex %daha seçenek var
                            
                        else
                             writematrix(selectedQuestionTitle, filenameSaveExcel,'Sheet',1,'Range',createExcelColumnName(1,(selectedQuestionIndex + 1)));
                  
                            if selectedAnswer ~= ""
                                writematrix(selectedAnswer, filenameSaveExcel,'Sheet',1,'Range',createExcelColumnName((selected_folder_id + 1),(selectedQuestionIndex+1)));
                                  
                                subplot(1,2,1);
                                rectangle('Position',[selected_x_coordinat , selected_y_coordinat, part_of_image_width , part_of_image_heigth], 'EdgeColor','green');
                                subplot(1,2,2);
                                rectangle('Position',[selected_x_coordinat , selected_y_coordinat, part_of_image_width , part_of_image_heigth], 'EdgeColor','green');
                            end
                            total_selected_pixel = 0;     
                            selectedAnswer = "";                      
                        end
                    end             
                end
            end  
            app.FormDefinitionMenu.Enable = "on";
            app.ImageProcessingDefinitionMenu.Enable = "on";
            app.RunImageProcessingMenu.Enable = "on";
            app.GetDatasMenu.Enable = "on";
            uiwait(msgbox("ImageFolder Processed","Success","modal"));
            app.GetDatasMenu.ForegroundColor = [0,0,0];
        end

        % Menu selected function: FromImageToExcelMenu
        function FromImageToExcel(app, event)
            global filenameSaveExcel;
            global filenameExcel;
            global excelQuestions;
            global excelImageProcessing;
            global excelOptions;
            global ImageProcessedFiles;
            global EditedFiles;
            global ImageFiles;
            global ReferanceImageFiles;
            global ImageProcessOrder;   

            app.GetDatasMenu.ForegroundColor = [0,1,0];
            app.FormDefinitionMenu.Enable = "off";
            app.ImageProcessingDefinitionMenu.Enable = "off";
            app.RunImageProcessingMenu.Enable = "off";
            app.GetDatasMenu.Enable = "off";

            app.AddImageProcessPanel.Visible = "off";
            app.UpdateImageProcessPanel.Visible = "off";
            app.EditQuestionPanel.Visible = "off"; 
            % uiwait(msgbox("Start to Transfer from Image to Excel.","Success","modal"));

            excelQuestions = readcell(filenameExcel,"Sheet",'QUESTIONS','Range','A1');
            excelQuestions(cellfun(@(x) any(ismissing(x)), excelQuestions)) = {''};

            excelOptions = readcell(filenameExcel,"Sheet",'OPTIONS','Range','A1');
            excelOptions(cellfun(@(x) any(ismissing(x)), excelOptions)) = {''};
 
            excelImageProcessing = readcell(filenameExcel,"Sheet",'IMAGEPROCESSNAME','Range','A1');
            excelImageProcessing(cellfun(@(x) any(ismissing(x)), excelImageProcessing)) = {''};
 
            files = dir(ImageFiles);
            % Get a logical vector that tells which is a directory.
            dirFlags = [files.isdir];
            % Extract only those that are directories.
            subDirs = files(dirFlags); % A structure with extra info.
            % Get only the folder names into a cell array.
            subDirsNames = {subDirs(3:end).name};            
            total_scan_folder = length(subDirsNames);
            
            oldselectedQuestionIndex = "-1";
            oldselectedAnswer = "";
            selectedAnswer = "";
            afterOptionQuestionIndex = "";
            
            total_selected_pixel = 0;
            selected_x_coordinat = 0;
            selected_y_coordinat = 0;
            for selected_folder_id=1:total_scan_folder
                writematrix(string(selected_folder_id), filenameSaveExcel,'Sheet',1,'Range', ('A' + string(selected_folder_id + 1)));
                for selected_option_id=1:height(excelOptions)
                   
                    selectedQuestionIndex =  str2double(string(excelOptions(selected_option_id,1)));
                    selectedQuestionTitle =  string(excelQuestions(selectedQuestionIndex,4));
                    selectedQuestion =  str2double(string(excelQuestions(selectedQuestionIndex,2)));
                    selectedOptionTitle =  string(excelOptions(selected_option_id,3));
                    % selectedImageProcessingId = str2double(excelQuestions(selectedQuestion,3));
                    % selectedImageProcessing = string(excelImageProcessing(selectedImageProcessingId,3));
                    selectedStandartImageFolder = string(excelQuestions(selectedQuestionIndex,5));
                    [filepath,imageName,imageExt] = fileparts(selectedStandartImageFolder);
                    % selectedImageFolder = ImageProcessedFiles + '\' + subDirsNames(selected_folder_id) + '\' + imageName + imageExt;
                    selectedProcessedImageFolder = string(getenv('USERPROFILE')) +'\Desktop\MD\ImageProcessedFolder\' + string(selected_folder_id) + '\' + string(selectedQuestion) + imageExt;
                    selectImageFolder = string(getenv('USERPROFILE')) +'\Desktop\MD\ImageEditedFolder\' + string(selected_folder_id) + '\' + string(selectedQuestion) + imageExt;
                    if ~isfile(selectedProcessedImageFolder)
                         % File does not exist.
                         ImageProcessing(app, event);
                    end
                    selectedProcessedImage = imread(selectedProcessedImageFolder);
                    selectImage = imread(selectImageFolder);
                   
                    part_of_image_x_coordinat = str2double(excelOptions(selected_option_id,4));
                    part_of_image_y_coordinat = str2double(excelOptions(selected_option_id,5));
                    part_of_image_width = str2double(excelOptions(selected_option_id,6));
                    part_of_image_heigth = str2double(excelOptions(selected_option_id,7));
                    part_of_image = imcrop(selectedProcessedImage,[part_of_image_x_coordinat, part_of_image_y_coordinat, part_of_image_width, part_of_image_heigth]);

                    total_dedect_pixel_from_part_of_image = sum(part_of_image,"all");
                    if  total_dedect_pixel_from_part_of_image > 0  
                        if  total_dedect_pixel_from_part_of_image > total_selected_pixel
                            total_selected_pixel = total_dedect_pixel_from_part_of_image;
                            selectedAnswer = string(excelOptions(selected_option_id,3)); 
                        end
                    end
                    if selected_option_id == height(excelOptions)                         
                         writematrix(selectedQuestionTitle, filenameSaveExcel,'Sheet',1,'Range',createExcelColumnName(1,(selectedQuestionIndex + 1)));
                         if selectedAnswer ~= ""
                            writematrix(selectedAnswer, filenameSaveExcel,'Sheet',1,'Range',createExcelColumnName((selected_folder_id + 1),(selectedQuestionIndex+1)));
                         end   
                        total_selected_pixel = 0;
                        selectedAnswer = "";
                    else
                        afterOptionQuestionIndex =  str2double(string(excelOptions((selected_option_id+1),1)));
                        if selectedQuestionIndex == afterOptionQuestionIndex; %daha seçenek var
                            
                        else
                             writematrix(selectedQuestionTitle, filenameSaveExcel,'Sheet',1,'Range',createExcelColumnName(1,(selectedQuestionIndex + 1)));
                  
                            if selectedAnswer ~= ""
                                writematrix(selectedAnswer, filenameSaveExcel,'Sheet',1,'Range',createExcelColumnName((selected_folder_id + 1),(selectedQuestionIndex+1)));
                            end
                            total_selected_pixel = 0;     
                            selectedAnswer = "";                      
                        end
                    end             
                end
            end  
            app.FormDefinitionMenu.Enable = "on";
            app.ImageProcessingDefinitionMenu.Enable = "on";
            app.RunImageProcessingMenu.Enable = "on";
            app.GetDatasMenu.Enable = "on";
            uiwait(msgbox("ImageFolder Processed","Success","modal"));
            winopen(string(getenv('USERPROFILE')) +'\Desktop\MD\ImageProcessedResult.xlsx');
            app.GetDatasMenu.ForegroundColor = [0,0,0];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FormDefinitionMenu = uimenu(app.UIFigure);
            app.FormDefinitionMenu.Text = 'Form Definition';
            app.AddQuestionandOptionMenu = uimenu(app.FormDefinitionMenu);
            app.AddQuestionandOptionMenu.MenuSelectedFcn = createCallbackFcn(app, @addQuestion, true);
            app.AddQuestionandOptionMenu.Text = 'Add Question and Option';
            app.UpdateQuestionMenu = uimenu(app.FormDefinitionMenu);
            app.UpdateQuestionMenu.MenuSelectedFcn = createCallbackFcn(app, @SoruGuncelleBtn, true);
            app.UpdateQuestionMenu.Text = 'Update Question';
            app.ImageProcessingDefinitionMenu = uimenu(app.UIFigure);
            app.ImageProcessingDefinitionMenu.Text = 'Image Processing Definition';
            app.AddImageProcessingMenu = uimenu(app.ImageProcessingDefinitionMenu);
            app.AddImageProcessingMenu.MenuSelectedFcn = createCallbackFcn(app, @GoruntuIslemEkleBtn, true);
            app.AddImageProcessingMenu.Text = 'Add Image Processing';
            app.UpdateImageProcessingMenu = uimenu(app.ImageProcessingDefinitionMenu);
            app.UpdateImageProcessingMenu.MenuSelectedFcn = createCallbackFcn(app, @UpdateProcess, true);
            app.UpdateImageProcessingMenu.Text = 'Update Image Processing';
            app.RunImageProcessingMenu = uimenu(app.UIFigure);
            app.RunImageProcessingMenu.MenuSelectedFcn = createCallbackFcn(app, @ImageProcessing, true);
            app.RunImageProcessingMenu.Text = 'Run Image Processing';
            app.GetDatasMenu = uimenu(app.UIFigure);
            app.GetDatasMenu.Text = 'Get Datas';
            app.FromImageToExcelMenu = uimenu(app.GetDatasMenu);
            app.FromImageToExcelMenu.MenuSelectedFcn = createCallbackFcn(app, @FromImageToExcel, true);
            app.FromImageToExcelMenu.Text = 'From Image To Excel';
            app.StepByStepReadImageMenu = uimenu(app.GetDatasMenu);
            app.StepByStepReadImageMenu.MenuSelectedFcn = createCallbackFcn(app, @showImageProcessing, true);
            app.StepByStepReadImageMenu.Text = 'Step By Step Read Image';
            app.EditQuestionPanel = uipanel(app.UIFigure);
            app.EditQuestionPanel.Title = 'Edit Question';
            app.EditQuestionPanel.Visible = 'off';
            app.UpdateQuestionButton = uibutton(app.EditQuestionPanel, 'push');
            app.UpdateQuestionButton.ButtonPushedFcn = createCallbackFcn(app, @updateQuestionInformations, true);
            app.UpdateQuestionButton.Text = 'Update Question';
            app.QuestionEditFieldLabel = uilabel(app.EditQuestionPanel);
            app.QuestionEditFieldLabel.Text = 'Question';
            app.QuestionEditField = uieditfield(app.EditQuestionPanel, 'text');
            app.QuestionEditField.Tag = 'soru_adi';
            app.QuestionOrderDropDownLabel = uilabel(app.EditQuestionPanel);
            app.QuestionOrderDropDownLabel.Text = 'Question Order';
            app.QuestionOrderDropDown = uidropdown(app.EditQuestionPanel);
            app.QuestionOrderDropDown.Items = {};
            app.QuestionOrderDropDown.ValueChangedFcn = createCallbackFcn(app, @changeQuestion, true);
            app.QuestionOrderDropDown.Tag = 'soru_sirasi';
            app.QuestionOrderDropDown.Value = {};
            app.CanYouEditTheQuestionButtonGroup = uibuttongroup(app.EditQuestionPanel);
            app.CanYouEditTheQuestionButtonGroup.Title = 'Can You Edit The Question';
            app.YesYouCanEditItButton = uiradiobutton(app.CanYouEditTheQuestionButtonGroup);
            app.YesYouCanEditItButton.Text = 'Yes, You Can Edit It';
            app.NoYouCantEditItButton = uiradiobutton(app.CanYouEditTheQuestionButtonGroup);
            app.NoYouCantEditItButton.Text = 'No, You Can''t Edit It';
            app.NoYouCantEditItButton.Value = true;
            app.ProcessOrderDropDown_2Label = uilabel(app.EditQuestionPanel);
            app.ProcessOrderDropDown_2Label.Text = 'Process Order';
            app.ProcessOrderDropDown_2 = uidropdown(app.EditQuestionPanel);
            app.ProcessOrderDropDown_2.Items = {};
            app.ProcessOrderDropDown_2.Value = {};
            app.AddImageProcessPanel = uipanel(app.UIFigure);
            app.AddImageProcessPanel.Title = 'Add Image Process';
            app.AddImageProcessPanel.Visible = 'off';
            app.ImageProcessTextArea_2Label = uilabel(app.AddImageProcessPanel);
            app.ImageProcessTextArea_2Label.Text = 'Image Process';
            app.ImageProcessTextArea_2 = uitextarea(app.AddImageProcessPanel);
            app.AddImageProcessButton = uibutton(app.AddImageProcessPanel, 'push');
            app.AddImageProcessButton.ButtonPushedFcn = createCallbackFcn(app, @InsertProcess, true);
            app.AddImageProcessButton.Text = 'Add Image Process';
            app.ProcessNameEditField_2Label = uilabel(app.AddImageProcessPanel);
            app.ProcessNameEditField_2Label.Text = 'Process Name';
            app.ProcessNameEditField_2 = uieditfield(app.AddImageProcessPanel, 'text');
            app.UpdateImageProcessPanel = uipanel(app.UIFigure);
            app.UpdateImageProcessPanel.Title = 'Update Image Process';
            app.UpdateImageProcessPanel.Visible = 'off';
            app.ProcessOrderDropDownLabel = uilabel(app.UpdateImageProcessPanel);
            app.ProcessOrderDropDownLabel.Text = 'Process Order';
            app.ProcessOrderDropDown = uidropdown(app.UpdateImageProcessPanel);
            app.ProcessOrderDropDown.Items = {};
            app.ProcessOrderDropDown.ValueChangedFcn = createCallbackFcn(app, @islemChange, true);
            app.ProcessOrderDropDown.Value = {};
            app.ImageProcessTextAreaLabel = uilabel(app.UpdateImageProcessPanel);
            app.ImageProcessTextAreaLabel.Text = 'Image Process';
            app.ImageProcessTextArea = uitextarea(app.UpdateImageProcessPanel);
            app.ImageProcessTextArea.ValueChangedFcn = createCallbackFcn(app, @functionProcessChange, true);
            app.ProcessNameEditFieldLabel = uilabel(app.UpdateImageProcessPanel);
            app.ProcessNameEditFieldLabel.Text = 'Process Name';
            app.ProcessNameEditField = uieditfield(app.UpdateImageProcessPanel, 'text');
            app.ProcessNameEditField.ValueChangedFcn = createCallbackFcn(app, @functionProcessChange, true);
            app.Hyperlink = uihyperlink(app.UIFigure);
            app.Hyperlink.URL = 'https://muratdelen.com';
            app.Hyperlink.Text = 'Create By Murat DELEN';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MD

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @loadVeriable)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end